classdef Final_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        Menu3                           matlab.ui.container.Menu
        Menu4                           matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        HazardInputPanel                matlab.ui.container.Panel
        OrderofFittingPolynomialSwitchLabel  matlab.ui.control.Label
        OrderofFittingPolynomialSwitch  matlab.ui.control.RockerSwitch
        FileUploadTextAreaLabel         matlab.ui.control.Label
        FileUploadTextArea              matlab.ui.control.TextArea
        LoadHazardCurveButton           matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        TextArea_5                      matlab.ui.control.TextArea
        TextArea_6                      matlab.ui.control.TextArea
        TextArea_7                      matlab.ui.control.TextArea
        toLabel_2                       matlab.ui.control.Label
        toLabel_3                       matlab.ui.control.Label
        HazardFitRangeLabel             matlab.ui.control.Label
        SaIntegrationRangeLabel         matlab.ui.control.Label
        StructureInputPanel             matlab.ui.container.Panel
        LoadStructureDataButton         matlab.ui.control.Button
        FileUploadTextArea_2Label       matlab.ui.control.Label
        FileUploadTextArea_2            matlab.ui.control.TextArea
        LoadStructureResponseButton     matlab.ui.control.Button
        FileUploadTextArea_3Label       matlab.ui.control.Label
        FileUploadTextArea_3            matlab.ui.control.TextArea
        FragilitiesandLossPanel         matlab.ui.container.Panel
        FileUploadTextArea_4Label       matlab.ui.control.Label
        FileUploadTextArea_4            matlab.ui.control.TextArea
        LoadFragilityLossDataButton     matlab.ui.control.Button
        DemolitionParametersPanel       matlab.ui.container.Panel
        MedianRIDRTextAreaLabel         matlab.ui.control.Label
        MedianRIDRTextArea              matlab.ui.control.TextArea
        DispersionRIDRTextAreaLabel     matlab.ui.control.Label
        DispersionRIDRTextArea          matlab.ui.control.TextArea
        RunAnalysisButton               matlab.ui.control.Button
        HazardTab                       matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        StructuralResponseTab           matlab.ui.container.Tab
        CollapseFragilityTab            matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        DamageFragilitiesTab            matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        LossEstimationTab               matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        Tab                             matlab.ui.container.Tab
        Tab_2                           matlab.ui.container.Tab
    end


    properties (Access = public)
        handles % Description
    end


    methods (Access = private)

        % Value changed function: FileUploadTextArea
        function FileUploadTextAreaValueChanged(app, event)
            app.handles.value = 0;
        end

        % Button pushed function: LoadHazardCurveButton
        function LoadHazardCurve(app, event)
             app.handles.hazardFile = uigetfile('*.*');
             if isequal(app.handles.hazardFile, 0)
                 disp('User select Cancel')
             else
                 disp(['Filename ', app.handles.hazardFile])
             end
        end

        % Value changed function: OrderofFittingPolynomialSwitch
        function PolynomialOrder(app, event)
            app.handles.PolynomialOrder = app.OrderofFittingPolynomialSwitch.Value;
        end

        % Button pushed function: LoadStructureDataButton
        function LoadStructure(app, event)
             app.handles.structureFile = uigetfile('*.*');
             if isequal(app.handles.structureFile, 0)
                 disp('User select Cancel')
             else
                 disp(['Filename ', app.handles.hazardFile])
             end
        end

        % Button pushed function: LoadStructureResponseButton
        function LoadResponse(app, event)
             app.handles.responseFiles = uigetfile('*.csv', 'Select all Files', 'MultiSelect', 'on');
             if isequal(app.handles.responseFiles, 0)
                 disp('User select Cancel')
             else
                 disp(['Filename ', app.handles.hazardFile])
             end
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysis(app, event)
            % Load in the hazard curve
            app.handles.hazardCurve = load(app.handles.hazardFile);
            curve
            
            [app.handles] = createPolyFit(order, curve, interval, dSa);
            
            % Initialize range of variables for integration
            app.handles.EDP.IDR = 0.0001:.0001:.15;
            app.handles.EDP.PFA = 0.001:.005:4.0;
            app.handles.EDP.RIDR = 0.0001:.0001:.1;
            
            % GUI Inputs
            ns = length(app.handles.responseFile);
            n = 30; % Can we get this from loading file?
            
            filenames = app.handles.responseFiles;
            [app.handles] = LoadStripeData(app.handles, filenames, ns);
            [app.handles] = ResponseEstimation(app.handles);
            
            [app.handles] = CollapseFragility(n, app.handles);
            
            filename = app.handles.fragilityFile;
            [app.handles] = loadComputeDamageFragilities(app.handles, filename);
            
            % Return expected loss given EDP conditioned on no collapse and no repair
            app.handles = expectedLoss_EDP(app.handles);
            
            % Return expected loss given IM conditioned on no collapse and no repair
            app.handles = expectedLoss_IM(app.handles);
            
            % Probability of Demolition Given No Collapse
            app.handles = ExpectedLoss(app.handles);
            
            % Find probabilities of all 3 ingredients (repair, demo, collapse) and plot
            % all loss functions given IM
            app.handles = repairCollapseDemoProb(app.handles);
            
            % Calculate the AAL and deaggregation ratios (could be used for fun things)
            app.handles = averageAnnualLoss(app.handles);
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.QuitMenu = uimenu(app.Menu);
            app.QuitMenu.Text = 'Quit';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.Text = 'Save';
            app.Menu3 = uimenu(app.Menu);
            app.Menu3.Text = 'Menu3';
            app.Menu4 = uimenu(app.Menu);
            app.Menu4.Text = 'Menu4';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.HazardInputPanel = uipanel(app.InputsTab);
            app.HazardInputPanel.Title = 'Hazard Input';
            app.OrderofFittingPolynomialSwitchLabel = uilabel(app.HazardInputPanel);
            app.OrderofFittingPolynomialSwitchLabel.Text = 'Order of Fitting Polynomial';
            app.OrderofFittingPolynomialSwitch = uiswitch(app.HazardInputPanel, 'rocker');
            app.OrderofFittingPolynomialSwitch.Items = {'3rd', '4th'};
            app.OrderofFittingPolynomialSwitch.ValueChangedFcn = createCallbackFcn(app, @PolynomialOrder, true);
            app.OrderofFittingPolynomialSwitch.Value = '3rd';
            app.FileUploadTextAreaLabel = uilabel(app.HazardInputPanel);
            app.FileUploadTextAreaLabel.Text = 'File Upload:';
            app.FileUploadTextArea = uitextarea(app.HazardInputPanel);
            app.FileUploadTextArea.ValueChangedFcn = createCallbackFcn(app, @FileUploadTextAreaValueChanged, true);
            app.FileUploadTextArea.Editable = 'off';
            app.LoadHazardCurveButton = uibutton(app.HazardInputPanel, 'push');
            app.LoadHazardCurveButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHazardCurve, true);
            app.LoadHazardCurveButton.Text = 'Load Hazard Curve';
            app.TextArea = uitextarea(app.HazardInputPanel);
            app.TextArea_5 = uitextarea(app.HazardInputPanel);
            app.TextArea_6 = uitextarea(app.HazardInputPanel);
            app.TextArea_7 = uitextarea(app.HazardInputPanel);
            app.toLabel_2 = uilabel(app.HazardInputPanel);
            app.toLabel_2.Text = 'to';
            app.toLabel_3 = uilabel(app.HazardInputPanel);
            app.toLabel_3.Text = 'to';
            app.HazardFitRangeLabel = uilabel(app.HazardInputPanel);
            app.HazardFitRangeLabel.Text = 'Hazard Fit Range:';
            app.SaIntegrationRangeLabel = uilabel(app.HazardInputPanel);
            app.SaIntegrationRangeLabel.Text = 'Sa Integration Range:';
            app.StructureInputPanel = uipanel(app.InputsTab);
            app.StructureInputPanel.Title = 'Structure Input';
            app.LoadStructureDataButton = uibutton(app.StructureInputPanel, 'push');
            app.LoadStructureDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStructure, true);
            app.LoadStructureDataButton.Text = 'Load Structure Data';
            app.FileUploadTextArea_2Label = uilabel(app.StructureInputPanel);
            app.FileUploadTextArea_2Label.Text = 'File Upload:';
            app.FileUploadTextArea_2 = uitextarea(app.StructureInputPanel);
            app.LoadStructureResponseButton = uibutton(app.StructureInputPanel, 'push');
            app.LoadStructureResponseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResponse, true);
            app.LoadStructureResponseButton.Text = 'Load Structure Response';
            app.FileUploadTextArea_3Label = uilabel(app.StructureInputPanel);
            app.FileUploadTextArea_3Label.Text = 'File Upload:';
            app.FileUploadTextArea_3 = uitextarea(app.StructureInputPanel);
            app.FragilitiesandLossPanel = uipanel(app.InputsTab);
            app.FragilitiesandLossPanel.Title = 'Fragilities and Loss';
            app.FileUploadTextArea_4Label = uilabel(app.FragilitiesandLossPanel);
            app.FileUploadTextArea_4Label.Text = 'File Upload:';
            app.FileUploadTextArea_4 = uitextarea(app.FragilitiesandLossPanel);
            app.LoadFragilityLossDataButton = uibutton(app.FragilitiesandLossPanel, 'push');
            app.LoadFragilityLossDataButton.Text = 'Load Fragility/Loss Data';
            app.DemolitionParametersPanel = uipanel(app.InputsTab);
            app.DemolitionParametersPanel.Title = 'Demolition Parameters:';
            app.MedianRIDRTextAreaLabel = uilabel(app.DemolitionParametersPanel);
            app.MedianRIDRTextAreaLabel.Text = 'Median RIDR:';
            app.MedianRIDRTextArea = uitextarea(app.DemolitionParametersPanel);
            app.MedianRIDRTextArea.Value = {'0.015'};
            app.DispersionRIDRTextAreaLabel = uilabel(app.DemolitionParametersPanel);
            app.DispersionRIDRTextAreaLabel.Text = 'Dispersion RIDR:';
            app.DispersionRIDRTextArea = uitextarea(app.DemolitionParametersPanel);
            app.DispersionRIDRTextArea.Value = {'0.3'};
            app.RunAnalysisButton = uibutton(app.InputsTab, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysis, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.HazardTab = uitab(app.TabGroup);
            app.HazardTab.Title = 'Hazard';
            app.UIAxes2 = uiaxes(app.HazardTab);
            title(app.UIAxes2, 'Title')
            ylabel(app.UIAxes2, 'Y')
            app.StructuralResponseTab = uitab(app.TabGroup);
            app.StructuralResponseTab.Title = 'Structural Response';
            app.CollapseFragilityTab = uitab(app.TabGroup);
            app.CollapseFragilityTab.Title = 'Collapse Fragility';
            app.UIAxes = uiaxes(app.CollapseFragilityTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DamageFragilitiesTab = uitab(app.TabGroup);
            app.DamageFragilitiesTab.Title = 'Damage Fragilities';
            app.UIAxes3 = uiaxes(app.DamageFragilitiesTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.LossEstimationTab = uitab(app.TabGroup);
            app.LossEstimationTab.Title = 'Loss Estimation';
            app.UIAxes4 = uiaxes(app.LossEstimationTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.Tab = uitab(app.TabGroup);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Tab';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Final_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end